classdef Colocalization_analyzer_exe2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Image1_Tab                    matlab.ui.container.Tab
        automaticsegmentationLabel    matlab.ui.control.Label
        DropDown2                     matlab.ui.control.DropDown
        ButtonImport1                 matlab.ui.control.Button
        LabelEditField                matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        Button9                       matlab.ui.control.Button
        Button21                      matlab.ui.control.Button
        UIAxes10                      matlab.ui.control.UIAxes
        removenoiseLabel              matlab.ui.control.Label
        Slider15                      matlab.ui.control.Slider
        UIAxes10_2                    matlab.ui.control.UIAxes
        SegmentButton                 matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        FreehandButton                matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        CancelButton_2                matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        InfoButton_2                  matlab.ui.control.Button
        ClearallButton                matlab.ui.control.Button
        Image2_Tab                    matlab.ui.container.Tab
        UIAxes10_3                    matlab.ui.control.UIAxes
        UIAxes10_4                    matlab.ui.control.UIAxes
        automaticsegmentationLabel_2  matlab.ui.control.Label
        DropDown2_2                   matlab.ui.control.DropDown
        ButtonImport1_2               matlab.ui.control.Button
        SegmentButton_2               matlab.ui.control.Button
        CancelButton_3                matlab.ui.control.Button
        FreehandButton_2              matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        CancelButton_4                matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
        removenoiseLabel_2            matlab.ui.control.Label
        Slider15_2                    matlab.ui.control.Slider
        LabelEditField_2              matlab.ui.control.Label
        EditField_2                   matlab.ui.control.EditField
        SeefullimageButton            matlab.ui.control.Button
        Image3_Tab                    matlab.ui.container.Tab
        UIAxes10_5                    matlab.ui.control.UIAxes
        UIAxes10_6                    matlab.ui.control.UIAxes
        automaticsegmentationLabel_3  matlab.ui.control.Label
        DropDown2_3                   matlab.ui.control.DropDown
        ButtonImport1_3               matlab.ui.control.Button
        SegmentButton_3               matlab.ui.control.Button
        CancelButton_5                matlab.ui.control.Button
        FreehandButton_3              matlab.ui.control.Button
        Button_5                      matlab.ui.control.Button
        CancelButton_6                matlab.ui.control.Button
        Button_6                      matlab.ui.control.Button
        Slider15_3                    matlab.ui.control.Slider
        LabelEditField_3              matlab.ui.control.Label
        EditField_3                   matlab.ui.control.EditField
        SeefullimageButton_2          matlab.ui.control.Button
        Image4_Tab                    matlab.ui.container.Tab
        UIAxes10_7                    matlab.ui.control.UIAxes
        UIAxes10_8                    matlab.ui.control.UIAxes
        automaticsegmentationLabel_4  matlab.ui.control.Label
        DropDown2_4                   matlab.ui.control.DropDown
        ButtonImport1_4               matlab.ui.control.Button
        SegmentButton_4               matlab.ui.control.Button
        CancelButton_7                matlab.ui.control.Button
        FreehandButton_4              matlab.ui.control.Button
        Button_7                      matlab.ui.control.Button
        CancelButton_8                matlab.ui.control.Button
        Button_8                      matlab.ui.control.Button
        Slider15_4                    matlab.ui.control.Slider
        LabelEditField_4              matlab.ui.control.Label
        EditField_4                   matlab.ui.control.EditField
        SeefullimageButton_3          matlab.ui.control.Button
        Analayze_Tab                  matlab.ui.container.Tab
        Analyze                       matlab.ui.control.Button
        CytoFluo                      matlab.ui.control.UIAxes
        Mask1Label                    matlab.ui.control.Label
        Mask1ListBox                  matlab.ui.control.ListBox
        Save                          matlab.ui.control.Button
        Mask2ListBoxLabel             matlab.ui.control.Label
        Mask2ListBox                  matlab.ui.control.ListBox
        Signal1DropDownLabel          matlab.ui.control.Label
        Signal1DropDown               matlab.ui.control.DropDown
        Signal2DropDownLabel          matlab.ui.control.Label
        Signal2DropDown               matlab.ui.control.DropDown
        UITable                       matlab.ui.control.Table
        ColMap                        matlab.ui.control.UIAxes
        InfoButton                    matlab.ui.control.Button
        InfoButton_3                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        % all the images and their crops
        Img
        % all the binary images and their"cleaned" version
        BW
        BW_er
        % "current" objects of the chosen tab: Image, axes to display images, name,
        % choice of segmentation, slider for removing outliers
        currentImg
        currentAxes_Img
        currentAxes_BW
        current_imgName
        currentDropDown
        curentSlider
        % images names and directory of the first loaded image
        img_names
        main_dir
        % table indicating which images are loaded
        loaded_image
        
        FreeRegion
        nb_free_ROI
        %coordinates of the crop: coordinates upper left corner + width, height
        cropzone
        
        % masks for the computation of correlation
        mask1
        mask2
        % signals for the computation of correlation
        signal1
        signal2
        % values of the signal for the computation of correlation (values in the mask)
        values1
        values2
        
        % correlation coefficients
        Rho
        pval
        Spearman
        M1
        M2
        coeffs
        % handles for the saving
        h1
        h2
        im_g
    end
    
    methods (Access = private)
        
        % refresh displays after crop and crop cancellation (display the correct image in each tab containing an image)
        function refresh_display(app)
            
            im = imshow(app.Img(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                app.cropzone(1):app.cropzone(1)+app.cropzone(3),1),[],'Parent',app.UIAxes10);
            app.UIAxes10.XLim = [0 im.XData(2)];
            app.UIAxes10.YLim = [0 im.YData(2)];
            im = imshow(app.BW_er(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                app.cropzone(1):app.cropzone(1)+app.cropzone(3),1),[],'Parent',app.UIAxes10_2);
            app.UIAxes10_2.XLim = [0 im.XData(2)];
            app.UIAxes10_2.YLim = [0 im.YData(2)];
            
            if ( app.loaded_image(2) == 1 )
                im = imshow(app.Img(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                    app.cropzone(1):app.cropzone(1)+app.cropzone(3),2),[],'Parent',app.UIAxes10_3);
                app.UIAxes10_3.XLim = [0 im.XData(2)];
                app.UIAxes10_3.YLim = [0 im.YData(2)];
                im = imshow(app.BW_er(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                    app.cropzone(1):app.cropzone(1)+app.cropzone(3),2),[],'Parent',app.UIAxes10_4);
                app.UIAxes10_4.XLim = [0 im.XData(2)];
                app.UIAxes10_4.YLim = [0 im.YData(2)];
            end
            
            if ( app.loaded_image(3) == 1 )
                im = imshow(app.Img(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                    app.cropzone(1):app.cropzone(1)+app.cropzone(3),3),[],'Parent',app.UIAxes10_5);
                app.UIAxes10_5.XLim = [0 im.XData(2)];
                app.UIAxes10_5.YLim = [0 im.YData(2)];
                im = imshow(app.BW_er(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                    app.cropzone(1):app.cropzone(1)+app.cropzone(3),3),[],'Parent',app.UIAxes10_6);
                app.UIAxes10_6.XLim = [0 im.XData(2)];
                app.UIAxes10_6.YLim = [0 im.YData(2)];
            end
            
            if ( app.loaded_image(4) == 1 )
                im = imshow(app.Img(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                    app.cropzone(1):app.cropzone(1)+app.cropzone(3),4),[],'Parent',app.UIAxes10_7);
                app.UIAxes10_7.XLim = [0 im.XData(2)];
                app.UIAxes10_7.YLim = [0 im.YData(2)];
                im = imshow(app.BW_er(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                    app.cropzone(1):app.cropzone(1)+app.cropzone(3),4),[],'Parent',app.UIAxes10_8);
                app.UIAxes10_8.XLim = [0 im.XData(2)];
                app.UIAxes10_8.YLim = [0 im.YData(2)];
            end
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %initialization of the "current" component and main dir at the current directory
            app.currentImg = 1;
            app.currentAxes_Img = app.UIAxes10;
            app.currentAxes_BW = app.UIAxes10_2;
            app.current_imgName = app.EditField;
            app.loaded_image = zeros(4,1);
            app.main_dir = pwd;
            app.currentDropDown = app.DropDown2;
            app.curentSlider = app.Slider15;
        end

        % Value changing function: Slider15, Slider15_2, Slider15_3, 
        % Slider15_4
        function Image1_noise(app, event)
            % equivalent to "remove outliers" of ImageJ: compute the local median filter (size given by the slider)
            % and replace the too large data by the median value
            level1 = event.Value;
            
            if( round(level1)~=0 )
                medBW = medfilt2(app.BW(:,:,app.currentImg),[round(level1) round(level1)]);
                tmp = app.BW(:,:,app.currentImg);
                tmp(tmp>=medBW) = medBW(tmp>=medBW);
                app.BW_er(:,:,app.currentImg) = tmp;
            else
                app.BW_er(:,:,app.currentImg) = app.BW(:,:,app.currentImg);
            end
            
            im = imshow(app.BW_er(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                app.cropzone(1):app.cropzone(1)+app.cropzone(3),app.currentImg),[],'Parent',app.currentAxes_BW);
            app.currentAxes_BW.XLim = [0 im.XData(2)];
            app.currentAxes_BW.YLim = [0 im.YData(2)];
        end

        % Button pushed function: ButtonImport1, ButtonImport1_2, 
        % ButtonImport1_3, ButtonImport1_4
        function ImportImage1(app, event)
            [app.img_names{app.currentImg},dir_image]=uigetfile(fullfile(app.main_dir,'*.tif'),'File Selector');
            
            info = imfinfo([dir_image,app.img_names{app.currentImg}]);
            Nf = length(info);
            
            if sum(app.loaded_image) == 0 %no image imported, initialization of images and main_dir
                app.Img = zeros(info(1).Height,info(1).Width,4);
                app.BW=zeros(info(1).Height,info(1).Width,4);
                app.BW_er=zeros(info(1).Height,info(1).Width,4);
                app.main_dir = dir_image;
                app.cropzone = [1 1 info(1).Height-1 info(1).Height-1]; % cropzone = the whole image
            end
            
            if(Nf > 1 ) %if stack image, the user can choose one or the maximum projection
                % list of offered choices
                list = {'Maximum projection'};
                for i_l=1:Nf
                    list{i_l+1} = ['Image ' num2str(i_l)];
                end
                [indx,~] = listdlg('ListString',list,'SelectionMode','single','PromptString','Select an image:');
                
                if (~isempty(indx))
                    if indx == 1 % maximum projection = max for each pixel on all slices
                        app.Img(:,:,app.currentImg) = imread(fullfile(dir_image,app.img_names{app.currentImg}),1);
                        for i_l=2:Nf
                            img = imread(fullfile(dir_image,app.img_names{app.currentImg}),i_l);
                            app.Img(:,:,app.currentImg) = max(app.Img(:,:,app.currentImg), double(img));
                        end
                    else
                        app.Img(:,:,app.currentImg) = imread(fullfile(dir_image,app.img_names{app.currentImg}),indx-1);
                    end
                end
            else
                app.Img(:,:,app.currentImg) = imread(fullfile(dir_image,app.img_names{app.currentImg}));
            end
            
            % display image name
            app.Image1Name();
            % indicates that the image is loaded
            app.loaded_image(app.currentImg) = 1;
            %display the image in the current axes
            im = imshow(app.Img(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                app.cropzone(1):app.cropzone(1)+app.cropzone(3),app.currentImg),[],'Parent',app.currentAxes_Img);
            app.currentAxes_Img.XLim = [0 im.XData(2)];
            app.currentAxes_Img.YLim = [0 im.YData(2)];
        end

        % Value changed function: EditField
        function Image1Name(app, event)
            % display the name of the image without extension
            [~,name_noext,~] = fileparts(app.img_names{app.currentImg});
            app.img_names{app.currentImg} = name_noext;
            set(app.current_imgName,'Value',name_noext);
        end

        % Button pushed function: Button9
        function crop(app, event)
            % enable to crop the data:
            % display the same image as the one displayed on the axes
            f=figure();imshow(app.Img(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                app.cropzone(1):app.cropzone(1)+app.cropzone(3),1),[]);
            % make a rectangle on the image
            h = imrect();
            pos = h.getPosition;
            % save the cropped zoom; app.cropzone(1) and app.cropzone(2) must be added to enable consecutive crops
            app.cropzone = int16([app.cropzone(1)+pos(1) app.cropzone(2)+pos(2) pos(3) pos(4)]);
            close(f);
            % refresh display in each tab (with the crop)
            app.refresh_display();
        end

        % Button pushed function: Button21
        function cancel_crop(app, event)
            % reset the crop zone to the whole image
            app.cropzone = [1 1 size(app.Img,1)-1 size(app.Img,2)-1];
            % refresh the display in all tabs
            app.refresh_display();
        end

        % Button pushed function: SegmentButton, SegmentButton_2, 
        % SegmentButton_3, SegmentButton_4
        function Segment(app, event)
            % diameter of the patch for the calculation
            ws=30;
            % get the name of the method for thresholding
            method=app.currentDropDown.Value;
            % computation of the thresholded image according to the chosen value
            app.BW(:,:,app.currentImg) = segment_local_threshold(app.Img(:,:,app.currentImg),ws,method);
            im=imshow(app.BW(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                app.cropzone(1):app.cropzone(1)+app.cropzone(3),app.currentImg),[],'Parent',app.currentAxes_BW);
            app.currentAxes_BW.XLim = [0 im.XData(2)];
            app.currentAxes_BW.YLim = [0 im.YData(2)];
            % initialization of the BW_er to BW
            app.BW_er(:,:,app.currentImg) = app.BW(:,:,app.currentImg);
        end

        % Button pushed function: CancelButton, CancelButton_3, 
        % CancelButton_5, CancelButton_7
        function cancel_seg(app, event)
            % cancel segmentation: BW and BW_er at 0
            app.BW(:,:,app.currentImg)=zeros(size(app.Img(:,:,app.currentImg)));
            app.BW_er(:,:,app.currentImg)=app.BW(:,:,app.currentImg);
            im = imshow(app.BW(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                app.cropzone(1):app.cropzone(1)+app.cropzone(3),app.currentImg),[],'Parent',app.currentAxes_BW);
            app.currentAxes_BW.XLim = [0 im.XData(2)];
            app.currentAxes_BW.YLim = [0 im.YData(2)];
            app.curentSlider.Value = 0; %reset of the slider
        end

        % Button pushed function: CancelButton_2, CancelButton_4, 
        % CancelButton_6, CancelButton_8
        function cancel_free(app, event)
            % cancel free region = equivalent to cancel seg + FreeRegion cleared
            app.BW(:,:,app.currentImg)=zeros(size(app.Img(:,:,app.currentImg)));
            app.BW_er(:,:,app.currentImg)=zeros(size(app.Img(:,:,app.currentImg)));
            im=imshow(app.BW(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                app.cropzone(1):app.cropzone(1)+app.cropzone(3),app.currentImg),[],'Parent',app.currentAxes_BW);
            app.currentAxes_BW.XLim = [0 im.XData(2)];
            app.currentAxes_BW.YLim = [0 im.YData(2)];
            app.nb_free_ROI=1;
            app.FreeRegion=[];
        end

        % Button pushed function: FreehandButton, FreehandButton_2, 
        % FreehandButton_3, FreehandButton_4
        function Freehand(app, event)
            % enables to draw the first ROI to keep on the displayed image (cropped if a crop was performed); 
            % the positions defining the ROI are stored in app.FreeRegion
            figure(),imshow(app.Img(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                app.cropzone(1):app.cropzone(1)+app.cropzone(3),app.currentImg),[]);
            ax1=gca;
            h1(1) = drawfreehand(ax1);
            app.BW_er(:,:,app.currentImg) = poly2mask(double(app.cropzone(1))+h1(1).Position(:,1), double(app.cropzone(2))+h1(1).Position(:,2),size(app.Img,1),size(app.Img,2));
            im=imshow(app.BW_er(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                app.cropzone(1):app.cropzone(1)+app.cropzone(3),app.currentImg),[],'Parent',app.currentAxes_BW);
            app.currentAxes_BW.XLim = [0 im.XData(2)];
            app.currentAxes_BW.YLim = [0 im.YData(2)];
            app.FreeRegion{1}=[h1(1).Position(:,1) h1(1).Position(:,2)];
            app.BW(:,:,app.currentImg) = app.BW_er(:,:,app.currentImg);
            app.nb_free_ROI=2;
        end

        % Button pushed function: Button, Button_3, Button_5, Button_7
        function Add_freehand(app, event)
            % enables to add ROIs on the displayed image (cropped if a crop was performed); 
            % the positions defining the ROI are added in app.FreeRegion
            ax1=gca;
            h1(app.nb_free_ROI) = drawfreehand(ax1);
            BW1_i = poly2mask(double(app.cropzone(1))+h1(app.nb_free_ROI).Position(:,1), double(app.cropzone(2))+h1(app.nb_free_ROI).Position(:,2),size(app.Img(:,:,1),1),size(app.Img(:,:,1),2));
            app.BW_er(:,:,app.currentImg) = app.BW_er(:,:,app.currentImg)|BW1_i;
            im=imshow(app.BW_er(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                app.cropzone(1):app.cropzone(1)+app.cropzone(3),app.currentImg),[],'Parent',app.currentAxes_BW);
            app.currentAxes_BW.XLim = [0 im.XData(2)];
            app.currentAxes_BW.YLim = [0 im.YData(2)];
            app.FreeRegion{app.nb_free_ROI}=[h1(app.nb_free_ROI).Position(:,1) h1(app.nb_free_ROI).Position(:,2)];
            app.BW(:,:,app.currentImg) = app.BW_er(:,:,app.currentImg);
            app.nb_free_ROI=app.nb_free_ROI+1;
        end

        % Value changed function: Mask1ListBox
        function Mask1(app, event)
            % compute the first mask according to the choices in the Mask1ListBox (multichoice possible)
            value = app.Mask1ListBox.Value;
            nb=size(value,2);
            app.mask1 = app.BW_er(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                app.cropzone(1):app.cropzone(1)+app.cropzone(3),str2num(value{1}));
            for i_im = 2:nb % if multiple mask
                app.mask1 = app.mask1 & app.BW_er(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                    app.cropzone(1):app.cropzone(1)+app.cropzone(3),str2num(value{i_im}));
            end
        end

        % Value changed function: Mask2ListBox
        function Mask2(app, event)
            % compute the first mask according to the choices in the Mask2ListBox (multichoice possible)
            value = app.Mask2ListBox.Value;
            nb=size(value,2);
            app.mask2 = app.BW_er(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                app.cropzone(1):app.cropzone(1)+app.cropzone(3),str2num(value{1}));
            for i_im = 2:nb % if multiple mask
                app.mask2 = app.mask2 & app.BW_er(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                    app.cropzone(1):app.cropzone(1)+app.cropzone(3),str2num(value{i_im}));
            end
        end

        % Button pushed function: Analyze
        function colocalize(app, event)
            % mask of colocalization: binary mask on which the correlation coefficients will be computed
            mask_coloc=app.mask1 | app.mask2;
            
            if (sum(sum(app.mask2 == zeros(size(app.mask2))))== size(app.mask2,1)*size(app.mask2,2) || ... %no mask
                    sum(sum(app.mask1 == zeros(size(app.mask2))))== size(app.mask2,1)*size(app.mask2,2) || ...
                    isempty(app.signal1) || isempty(app.signal2)) %no chosen signal
                % data not segmented
                msgbox('At least one of your chosen image is not binarized or one of the signal is not chosen.');
            else
                % XY contains the pixel different from 0 in the mask
                [XY]=find(mask_coloc);
                app.values1=app.signal1(XY);
                app.values2=app.signal2(XY);
                
                [app.Rho,app.pval,RLO,RUP]= corrcoef(app.values1, app.values2, 'alpha', 0.05);
                Spearman=corr(app.values1, app.values2, 'Type', 'Spearman');
                signal1in2pos=app.signal1(find(app.mask1 & app.mask2));
                app.M1=sum(signal1in2pos(:))/sum(app.signal1(find(app.mask1)));
                signal2in1pos=app.signal2(find(app.mask1 & app.mask2));
                app.M2=sum(signal2in1pos(:))/sum(app.signal2(find(app.mask2)));
                % display the coefficients in the interface
                app.coeffs={};
                app.coeffs(:,1)={'Pearson';'Spearman';'Manders M1';'Manders M2'};
                app.coeffs(:,2)={app.Rho(1,2); Spearman; app.M1;app.M2};
                app.UITable.Data = app.coeffs(:,2);
                
                %cytofluo 
                s = scatter(app.values1,app.values2,'Parent',app.CytoFluo);
                app.CytoFluo.XLim = [min(s.XData)-20 max(s.XData)+20];
                app.CytoFluo.YLim = [min(s.YData)-20 max(s.YData)+20];
                app.CytoFluo.XLabel.String = strrep(app.img_names(str2num(app.Signal1DropDown.Value)),'_', ' '); % remplace les _ par des espaces pour éviter qu'ils soient transformés en "indice" (comme en latex)
                app.CytoFluo.YLabel.String = strrep(app.img_names(str2num(app.Signal2DropDown.Value)),'_', ' ');
                
                % computation of the colocalization colormap (computed by normalized mean deviation product -nMDP)
                maxA = double(max(app.signal1(XY)));
                maxB = double(max(app.signal2(XY)));
                meanA = double(mean(app.signal1(XY)));
                meanB = double(mean(app.signal2(XY)));
               
                img_cor =  ((app.signal1.*mask_coloc-meanA.*mask_coloc).*(app.signal2.*mask_coloc-meanB.*mask_coloc))/( (maxA-meanA)*(maxB-meanB) );
                app.im_g = imgaussfilt(img_cor,1);
                
                val_min_im_g = min(app.im_g(mask_coloc ==1 ));
                app.im_g(mask_coloc ==0 ) = val_min_im_g-0.5;
                
                % modification of the colormap to have the bcakground in black
                jet_black = jet;
                jet_black(1:2,:) = [0 0 0; 0 0 0];

                g = imshow(app.im_g,[],'Parent',app.ColMap) ;
                app.ColMap.XLim = [0 max(g.XData)];
                app.ColMap.YLim = [0 max(g.YData)];
                app.ColMap.Colormap=jet_black; cb = colorbar(app.ColMap); cb.Limits = [min(app.im_g(:)),max(app.im_g(:))];
            end
        end

        % Value changed function: Signal1DropDown
        function Fluo1(app, event)
            % compute the first signal according to the choice in Signal1DropDown
            value = app.Signal1DropDown.Value;
            if( ~isempty(str2num(value)) ) % if one signal is chosen
                app.signal1=app.Img(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                    app.cropzone(1):app.cropzone(1)+app.cropzone(3),str2num(value));
            end
        end

        % Value changed function: Signal2DropDown
        function Fluo2(app, event)
            % compute the second signal according to the choice in Signal1DropDown
            value = app.Signal2DropDown.Value;
            if( ~isempty(str2num(value)) )  % if one signal is chosen
                app.signal2=app.Img(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                    app.cropzone(1):app.cropzone(1)+app.cropzone(3),str2num(value));
            end
        end

        % Button pushed function: Save
        function SaveRes(app, event)
            [file,path] = uiputfile(' ', 'save dat and figure as');
            [~,file,~] = fileparts(file);
            cd(path);
            img_list=[app.img_names{str2num(app.Signal1DropDown.Value)},'    vs    ',app.img_names{str2num(app.Signal2DropDown.Value)}];
            img_list = strrep(img_list,'_', ' ');
            mask_coloc=app.mask1 | app.mask2;
            h2=figure;
            %sgtitle(img_list);
            ax1 = subplot(1,2,1); imshow(mask_coloc,[]); title('Mask');
            set(gca, 'Position', [0.05 0.1 0.4 0.85])
            colormap(ax1,gray);
            
            jet_black = jet;
            jet_black(1:2,:) = [0 0 0; 0 0 0];
            val_min_im_g = min(app.im_g(mask_coloc ==1 ));
            ax2 = subplot(1,2,2); imshow(app.im_g,[]); colormap (ax2,jet_black) ; title('Colocalization Map'); cb = colorbar;
            cb.Limits = [val_min_im_g max(cb.Limits)];
            set(gca, 'Position', [0.50 0.1 0.4 0.85])
            %             filename_P=[file,'_Pearson'];
            %             savefig(h1,filename_P);
            filename_M=[file,'_Map'];
            filename_C=[file,'_Coefs.xlsx'];
            %savefig(h2,filename_M);
            saveas(h2,filename_M,'tiff')
            close (h2)       
            
            t = table(app.coeffs(:,1),app.coeffs(:,2),'VariableNames',{'Method','Coefficient'});
            writetable(t,filename_C);
            
            values1=app.values1;
            values2=app.values2;
%             sig_names=[app.img_names{str2num(app.Signal1DropDown.Value)};app.img_names{str2num(app.Signal2DropDown.Value)}];
%             sig_names1=sig_names(1,:);
%             sig_names2=sig_names(2,:);
            sig_names={app.img_names{str2num(app.Signal1DropDown.Value)};app.img_names{str2num(app.Signal2DropDown.Value)}};
            sig_names1=sig_names{1};
            sig_names2=sig_names{2};
            sig_names1(regexp(sig_names1,'[-,_,.,+, ]'))=[];
            sig_names2(regexp(sig_names2,'[-,_,.,+, ]'))=[];
            %sig_names2(1,:)=strrep(sig_names(1,:),'_','');
            %sig_names2(2,:)=strrep(sig_names(2,:),'_','');
            %sig_names3(1,:)=strrep(sig_names(1,:),'-','');
            %sig_names3(2,:)=strrep(sig_names(2,:),'-','');
            v = table(values1,values2,'VariableNames',{sig_names1,sig_names2});
            
            filename_V=[file,'_PixValues.xlsx'];
            writetable(v,filename_V);
            
            cd(app.main_dir);
        end

        % Selection change function: TabGroup
        function changeTab(app, event)
            % this function updates all the "current_*" properties (axes, names, slider, segmentation choices)
            ind_num = (regexp(app.TabGroup.SelectedTab.Title,'\d'));
            if( ~isempty(ind_num) ) %app.currentImg is the index of the last dimension for app.Img and app.BW
                app.currentImg = str2num(app.TabGroup.SelectedTab.Title(ind_num));
            else
                app.currentImg = 0;
            end
            
            switch app.currentImg
                case 1
                    app.currentAxes_Img = app.UIAxes10;
                    app.currentAxes_BW = app.UIAxes10_2;
                    app.current_imgName = app.EditField;
                    app.currentDropDown = app.DropDown2;
                    app.curentSlider = app.Slider15;
                case 2
                    app.currentAxes_Img = app.UIAxes10_3;
                    app.currentAxes_BW = app.UIAxes10_4;
                    app.current_imgName = app.EditField_2;
                    app.currentDropDown = app.DropDown2_2;
                    app.curentSlider = app.Slider15_2;
                case 3
                    app.currentAxes_Img = app.UIAxes10_5;
                    app.currentAxes_BW = app.UIAxes10_6;
                    app.current_imgName = app.EditField_3;
                    app.currentDropDown = app.DropDown2_3;
                    app.curentSlider = app.Slider15_3;
                case 4
                    app.currentAxes_Img = app.UIAxes10_7;
                    app.currentAxes_BW = app.UIAxes10_8;
                    app.current_imgName = app.EditField_4;
                    app.currentDropDown = app.DropDown2_4;
                    app.curentSlider = app.Slider15_4;
                otherwise %analyse tab
                    if( ~isempty(app.Img) ) %updates the image choices according to the loaded images
                        ind_list = 1;
                        for ind_I = 1:size(app.Img,3)
                            if app.loaded_image(ind_I) == 1
                                app.Mask1ListBox.Items{1,ind_list} = ['Image ' num2str(ind_I)];
                                app.Mask1ListBox.ItemsData{1,ind_list} = num2str(ind_I);
                                app.Mask2ListBox.Items{1,ind_list} = ['Image ' num2str(ind_I)];
                                app.Mask2ListBox.ItemsData{1,ind_list} = num2str(ind_I);
                                app.Signal1DropDown.Items{1,ind_list+1} = ['Image ' num2str(ind_I)];
                                app.Signal1DropDown.ItemsData{1,ind_list+1} = num2str(ind_I);
                                app.Signal2DropDown.Items{1,ind_list+1} = ['Image ' num2str(ind_I)];
                                app.Signal2DropDown.ItemsData{1,ind_list+1} = num2str(ind_I);
                                ind_list = ind_list+1;
                            end
                        end
                        
                        % In case where the images were modified after a first analysis, load the modified data (ex: a crop has been performed)
                        app.Mask1();
                        app.Mask2();
                        if(~isempty(str2num(app.Signal1DropDown.Value)))
                            app.Fluo1();
                        end
                        if(~isempty(str2num(app.Signal2DropDown.Value)))
                            app.Fluo2();
                        end
                    end
            end
        end

        % Button pushed function: SeefullimageButton, 
        % SeefullimageButton_2, SeefullimageButton_3
        function seefullImage(app, event)
            % show the whole image with the crop in a red rectangle 
            figure; imshow(app.Img(:,:,app.currentImg),[]);
            if ~isempty(app.cropzone)
                rectangle('Position',app.cropzone,'EdgeColor','r')
            end
        end

        % Button pushed function: Button_2, Button_4, Button_6, 
        % Button_8
        function Remove_freehand(app, event)
            % removes the latest created region by freehand
            if app.nb_free_ROI-1>=1
                app.nb_free_ROI=app.nb_free_ROI-1;
                BW1_i = poly2mask(double(app.cropzone(1))+app.FreeRegion{app.nb_free_ROI}(:,1), double(app.cropzone(2))+app.FreeRegion{app.nb_free_ROI}(:,2),size(app.Img(:,:,1),1),size(app.Img(:,:,1),2));
                app.BW_er(:,:,app.currentImg) = app.BW_er(:,:,app.currentImg) - BW1_i;
                app.FreeRegion(app.nb_free_ROI)=[];
                im=imshow(app.BW_er(app.cropzone(2):app.cropzone(2)+app.cropzone(4),...
                    app.cropzone(1):app.cropzone(1)+app.cropzone(3),app.currentImg),[],'Parent',app.currentAxes_BW);
                app.currentAxes_BW.XLim = [0 im.XData(2)];
                app.currentAxes_BW.YLim = [0 im.YData(2)];
            end
            app.BW(:,:,app.currentImg) = app.BW_er(:,:,app.currentImg);
        end

        % Button pushed function: InfoButton
        function Help_mask(app, event)
            f = uifigure;
            message = sprintf('Choose the ROIs where you want to measure colocalization. \n The resulting ROI is the addition of mask 1 and mask 2. \n For each mask you can choose ROIs from several pictures, it will then be the intersection of these ROIs. \n \n Then you need to choose from which images to take the signals that will be tested for colocalization in these ROIs.');
            uialert(f,message,'Help','Icon','info');
        end

        % Button pushed function: InfoButton_2
        function Help_Segment(app, event)
            f = uifigure;
            message = sprintf('You need to segment images, either automatically or by drawing one or several ROIs (freehand). \n Outliers can be removed ("remove noise" slider).');
            uialert(f,message,'Help','Icon','info');
        end

        % Button pushed function: InfoButton_3
        function Help_coefs(app, event)
            f = uifigure;
            message = sprintf('Colocalization as measured by correlation coefficients: \n - Pearson Linear Correlation Coefficient \n - Spearman Rank Correlation \n - Manders Split Coefficients M1 and M2. M1 is the proportion of signal 1 overlapping with signal 2, and conversely');
            uialert(f,message,'Help','Icon','info');
        end

        % Button pushed function: ClearallButton
        function clear_all(app, event)
            %% clear all the data for a new computation
             app.Img = [];
             app.loaded_image = zeros(4,1);
             app.BW = [];
             app.BW_er = [];
             app.img_names = [];
             app.UIAxes10.cla;
             app.UIAxes10_2.cla;
             app.UIAxes10_3.cla;
             app.UIAxes10_4.cla;
             app.UIAxes10_5.cla;
             app.UIAxes10_6.cla;
             app.UIAxes10_7.cla;
             app.UIAxes10_8.cla;
             app.ColMap.cla;
             app.CytoFluo.cla;
             app.CytoFluo.XLabel.String ='';app.CytoFluo.YLabel.String ='';
             set(app.UITable, 'Data', {});
             app.Mask1ListBox.Items = {'', '', '', ''};
             app.Mask1ListBox.ItemsData = {'1', '2', '3', '4'};
             app.Mask2ListBox.Items ={'', '', '', ''};
             app.Mask2ListBox.ItemsData = {'1', '2', '3', '4'};
             app.Signal1DropDown.Items ={'', '', '', ''};
             app.Signal1DropDown.ItemsData = {'1', '2', '3', '4'};
             app.Signal2DropDown.Items = {'', '', '', ''};
             app.Signal2DropDown.ItemsData = {'1', '2', '3', '4'};
             cb = colorbar(app.ColMap);
             cb.Visible = 'off';
             set(app.Slider15, 'value', 0);
             set(app.Slider15_2, 'value', 0);
             set(app.Slider15_3, 'value', 0);
             set(app.Slider15_4, 'value', 0);
            
             set(app.EditField,'Value','');
             set(app.EditField_2,'Value','');
             set(app.EditField_3,'Value','');
             set(app.EditField_4,'Value','');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @changeTab, true);
            app.Image1_Tab = uitab(app.TabGroup);
            app.Image1_Tab.Title = 'Image 1';
            app.automaticsegmentationLabel = uilabel(app.Image1_Tab);
            app.automaticsegmentationLabel.Text = 'automatic segmentation';
            app.DropDown2 = uidropdown(app.Image1_Tab);
            app.DropDown2.Items = {'choose a method', 'Phansalkar', 'MidGrey'};
            app.DropDown2.ItemsData = {'choose a method', 'Phansalkar', 'MidGrey', ''};
            app.DropDown2.Value = 'choose a method';
            app.ButtonImport1 = uibutton(app.Image1_Tab, 'push');
            app.ButtonImport1.ButtonPushedFcn = createCallbackFcn(app, @ImportImage1, true);
            app.ButtonImport1.Text = 'import image 1';
            app.LabelEditField = uilabel(app.Image1_Tab);
            app.LabelEditField.Text = 'Name';
            app.EditField = uieditfield(app.Image1_Tab, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @Image1Name, true);
            app.Button9 = uibutton(app.Image1_Tab, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @crop, true);
            app.Button9.Text = 'crop';
            app.Button21 = uibutton(app.Image1_Tab, 'push');
            app.Button21.ButtonPushedFcn = createCallbackFcn(app, @cancel_crop, true);
            app.Button21.Text = 'Cancel';
            app.UIAxes10 = uiaxes(app.Image1_Tab);
            title(app.UIAxes10, 'Original image')
            app.UIAxes10.XTick = [];
            app.UIAxes10.YTick = [];
            app.removenoiseLabel = uilabel(app.Image1_Tab);
            app.removenoiseLabel.Text = {'remove'; 'noise'};
            app.Slider15 = uislider(app.Image1_Tab);
            app.Slider15.Limits = [0 15];
            app.Slider15.ValueChangingFcn = createCallbackFcn(app, @Image1_noise, true);
            app.UIAxes10_2 = uiaxes(app.Image1_Tab);
            title(app.UIAxes10_2, 'Segmented image')
            app.UIAxes10_2.XTick = [];
            app.UIAxes10_2.YTick = [];
            app.SegmentButton = uibutton(app.Image1_Tab, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @Segment, true);
            app.SegmentButton.Text = 'Segment';
            app.CancelButton = uibutton(app.Image1_Tab, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel_seg, true);
            app.CancelButton.Text = 'Cancel';
            app.FreehandButton = uibutton(app.Image1_Tab, 'push');
            app.FreehandButton.ButtonPushedFcn = createCallbackFcn(app, @Freehand, true);
            app.FreehandButton.Text = 'Freehand';
            app.Button = uibutton(app.Image1_Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @Add_freehand, true);
            app.Button.Text = '+';
            app.CancelButton_2 = uibutton(app.Image1_Tab, 'push');
            app.CancelButton_2.ButtonPushedFcn = createCallbackFcn(app, @cancel_free, true);
            app.CancelButton_2.Text = 'Cancel';
            app.Button_2 = uibutton(app.Image1_Tab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Remove_freehand, true);
            app.Button_2.Text = '-';
            app.InfoButton_2 = uibutton(app.Image1_Tab, 'push');
            app.InfoButton_2.ButtonPushedFcn = createCallbackFcn(app, @Help_Segment, true);
            app.InfoButton_2.Text = 'Info';
            app.ClearallButton = uibutton(app.Image1_Tab, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @clear_all, true);
            app.ClearallButton.Text = 'Clear all';
            app.Image2_Tab = uitab(app.TabGroup);
            app.Image2_Tab.Title = 'Image 2';
            app.UIAxes10_3 = uiaxes(app.Image2_Tab);
            title(app.UIAxes10_3, 'Original image')
            app.UIAxes10_3.XTick = [];
            app.UIAxes10_3.YTick = [];
            app.UIAxes10_4 = uiaxes(app.Image2_Tab);
            title(app.UIAxes10_4, 'Segmented image')
            app.UIAxes10_4.XTick = [];
            app.UIAxes10_4.YTick = [];
            app.automaticsegmentationLabel_2 = uilabel(app.Image2_Tab);
            app.automaticsegmentationLabel_2.Text = 'automatic segmentation';
            app.DropDown2_2 = uidropdown(app.Image2_Tab);
            app.DropDown2_2.Items = {'choose a method', 'Phansalkar', 'MidGrey'};
            app.DropDown2_2.ItemsData = {'choose a method', 'Phansalkar', 'MidGrey', 'freehand'};
            app.DropDown2_2.Value = 'choose a method';
            app.ButtonImport1_2 = uibutton(app.Image2_Tab, 'push');
            app.ButtonImport1_2.ButtonPushedFcn = createCallbackFcn(app, @ImportImage1, true);
            app.ButtonImport1_2.Text = 'import image 2';
            app.SegmentButton_2 = uibutton(app.Image2_Tab, 'push');
            app.SegmentButton_2.ButtonPushedFcn = createCallbackFcn(app, @Segment, true);
            app.SegmentButton_2.Text = 'Segment';
            app.CancelButton_3 = uibutton(app.Image2_Tab, 'push');
            app.CancelButton_3.ButtonPushedFcn = createCallbackFcn(app, @cancel_seg, true);
            app.CancelButton_3.Text = 'Cancel';
            app.FreehandButton_2 = uibutton(app.Image2_Tab, 'push');
            app.FreehandButton_2.ButtonPushedFcn = createCallbackFcn(app, @Freehand, true);
            app.FreehandButton_2.Text = 'Freehand';
            app.Button_3 = uibutton(app.Image2_Tab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Add_freehand, true);
            app.Button_3.Text = '+';
            app.CancelButton_4 = uibutton(app.Image2_Tab, 'push');
            app.CancelButton_4.ButtonPushedFcn = createCallbackFcn(app, @cancel_free, true);
            app.CancelButton_4.Text = 'Cancel';
            app.Button_4 = uibutton(app.Image2_Tab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Remove_freehand, true);
            app.Button_4.Text = '-';
            app.removenoiseLabel_2 = uilabel(app.Image2_Tab);
            app.removenoiseLabel_2.Text = {'remove'; 'noise'};
            app.Slider15_2 = uislider(app.Image2_Tab);
            app.Slider15_2.Limits = [0 15];
            app.Slider15_2.ValueChangingFcn = createCallbackFcn(app, @Image1_noise, true);
            app.LabelEditField_2 = uilabel(app.Image2_Tab);
            app.LabelEditField_2.Text = 'Name';
            app.EditField_2 = uieditfield(app.Image2_Tab, 'text');
            app.SeefullimageButton = uibutton(app.Image2_Tab, 'push');
            app.SeefullimageButton.ButtonPushedFcn = createCallbackFcn(app, @seefullImage, true);
            app.SeefullimageButton.Text = 'See full image';
            app.Image3_Tab = uitab(app.TabGroup);
            app.Image3_Tab.Title = 'Image 3';
            app.UIAxes10_5 = uiaxes(app.Image3_Tab);
            title(app.UIAxes10_5, 'Original image')
            app.UIAxes10_5.XTick = [];
            app.UIAxes10_5.YTick = [];
            app.UIAxes10_6 = uiaxes(app.Image3_Tab);
            title(app.UIAxes10_6, 'Segmented image')
            app.UIAxes10_6.XTick = [];
            app.UIAxes10_6.YTick = [];
            app.automaticsegmentationLabel_3 = uilabel(app.Image3_Tab);
            app.automaticsegmentationLabel_3.Text = 'automatic segmentation';
            app.DropDown2_3 = uidropdown(app.Image3_Tab);
            app.DropDown2_3.Items = {'choose a method', 'Phansalkar', 'MidGrey'};
            app.DropDown2_3.ItemsData = {'choose a method', 'Phansalkar', 'MidGrey', 'freehand'};
            app.DropDown2_3.Value = 'choose a method';
            app.ButtonImport1_3 = uibutton(app.Image3_Tab, 'push');
            app.ButtonImport1_3.ButtonPushedFcn = createCallbackFcn(app, @ImportImage1, true);
            app.ButtonImport1_3.Text = 'import image 3';
            app.SegmentButton_3 = uibutton(app.Image3_Tab, 'push');
            app.SegmentButton_3.ButtonPushedFcn = createCallbackFcn(app, @Segment, true);
            app.SegmentButton_3.Text = 'Segment';
            app.CancelButton_5 = uibutton(app.Image3_Tab, 'push');
            app.CancelButton_5.ButtonPushedFcn = createCallbackFcn(app, @cancel_seg, true);
            app.CancelButton_5.Text = 'Cancel';
            app.FreehandButton_3 = uibutton(app.Image3_Tab, 'push');
            app.FreehandButton_3.ButtonPushedFcn = createCallbackFcn(app, @Freehand, true);
            app.FreehandButton_3.Text = 'Freehand';
            app.Button_5 = uibutton(app.Image3_Tab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Add_freehand, true);
            app.Button_5.Text = '+';
            app.CancelButton_6 = uibutton(app.Image3_Tab, 'push');
            app.CancelButton_6.ButtonPushedFcn = createCallbackFcn(app, @cancel_free, true);
            app.CancelButton_6.Text = 'Cancel';
            app.Button_6 = uibutton(app.Image3_Tab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Remove_freehand, true);
            app.Button_6.Text = '-';
            app.Slider15_3 = uislider(app.Image3_Tab);
            app.Slider15_3.Limits = [0 15];
            app.Slider15_3.ValueChangingFcn = createCallbackFcn(app, @Image1_noise, true);
            app.LabelEditField_3 = uilabel(app.Image3_Tab);
            app.LabelEditField_3.Text = 'Name';
            app.EditField_3 = uieditfield(app.Image3_Tab, 'text');
            app.SeefullimageButton_2 = uibutton(app.Image3_Tab, 'push');
            app.SeefullimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @seefullImage, true);
            app.SeefullimageButton_2.Text = 'See full image';
            app.Image4_Tab = uitab(app.TabGroup);
            app.Image4_Tab.Title = 'Image 4';
            app.UIAxes10_7 = uiaxes(app.Image4_Tab);
            title(app.UIAxes10_7, 'Original image')
            app.UIAxes10_7.XTick = [];
            app.UIAxes10_7.YTick = [];
            app.UIAxes10_8 = uiaxes(app.Image4_Tab);
            title(app.UIAxes10_8, 'Segmented image')
            app.UIAxes10_8.XTick = [];
            app.UIAxes10_8.YTick = [];
            app.automaticsegmentationLabel_4 = uilabel(app.Image4_Tab);
            app.automaticsegmentationLabel_4.Text = 'automatic segmentation';
            app.DropDown2_4 = uidropdown(app.Image4_Tab);
            app.DropDown2_4.Items = {'choose a method', 'Phansalkar', 'MidGrey'};
            app.DropDown2_4.ItemsData = {'choose a method', 'Phansalkar', 'MidGrey', 'freehand'};
            app.DropDown2_4.Value = 'choose a method';
            app.ButtonImport1_4 = uibutton(app.Image4_Tab, 'push');
            app.ButtonImport1_4.ButtonPushedFcn = createCallbackFcn(app, @ImportImage1, true);
            app.ButtonImport1_4.Text = 'import image 4';
            app.SegmentButton_4 = uibutton(app.Image4_Tab, 'push');
            app.SegmentButton_4.ButtonPushedFcn = createCallbackFcn(app, @Segment, true);
            app.SegmentButton_4.Text = 'Segment';
            app.CancelButton_7 = uibutton(app.Image4_Tab, 'push');
            app.CancelButton_7.ButtonPushedFcn = createCallbackFcn(app, @cancel_seg, true);
            app.CancelButton_7.Text = 'Cancel';
            app.FreehandButton_4 = uibutton(app.Image4_Tab, 'push');
            app.FreehandButton_4.ButtonPushedFcn = createCallbackFcn(app, @Freehand, true);
            app.FreehandButton_4.Text = 'Freehand';
            app.Button_7 = uibutton(app.Image4_Tab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Add_freehand, true);
            app.Button_7.Text = '+';
            app.CancelButton_8 = uibutton(app.Image4_Tab, 'push');
            app.CancelButton_8.ButtonPushedFcn = createCallbackFcn(app, @cancel_free, true);
            app.CancelButton_8.Text = 'Cancel';
            app.Button_8 = uibutton(app.Image4_Tab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Remove_freehand, true);
            app.Button_8.Text = '-';
            app.Slider15_4 = uislider(app.Image4_Tab);
            app.Slider15_4.Limits = [0 15];
            app.Slider15_4.ValueChangingFcn = createCallbackFcn(app, @Image1_noise, true);
            app.LabelEditField_4 = uilabel(app.Image4_Tab);
            app.LabelEditField_4.Text = 'Name';
            app.EditField_4 = uieditfield(app.Image4_Tab, 'text');
            app.SeefullimageButton_3 = uibutton(app.Image4_Tab, 'push');
            app.SeefullimageButton_3.ButtonPushedFcn = createCallbackFcn(app, @seefullImage, true);
            app.SeefullimageButton_3.Text = 'See full image';
            app.Analayze_Tab = uitab(app.TabGroup);
            app.Analayze_Tab.Title = 'Analyze';
            app.Analyze = uibutton(app.Analayze_Tab, 'push');
            app.Analyze.ButtonPushedFcn = createCallbackFcn(app, @colocalize, true);
            app.Analyze.Text = 'Analyze';
            app.CytoFluo = uiaxes(app.Analayze_Tab);
            title(app.CytoFluo, 'Cytofluorogram')
            xlabel(app.CytoFluo, 'signal 1')
            ylabel(app.CytoFluo, 'signal 2')
            app.Mask1Label = uilabel(app.Analayze_Tab);
            app.Mask1Label.Text = 'Mask 1';
            app.Mask1ListBox = uilistbox(app.Analayze_Tab);
            app.Mask1ListBox.Items = {'', '', '', ''};
            app.Mask1ListBox.ItemsData = {'1', '2', '3', '4'};
            app.Mask1ListBox.Multiselect = 'on';
            app.Mask1ListBox.ValueChangedFcn = createCallbackFcn(app, @Mask1, true);
            app.Mask1ListBox.Value = {'1'};
            app.Save = uibutton(app.Analayze_Tab, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveRes, true);
            app.Save.Text = 'Save';
            app.Mask2ListBoxLabel = uilabel(app.Analayze_Tab);
            app.Mask2ListBoxLabel.Text = 'Mask 2';
            app.Mask2ListBox = uilistbox(app.Analayze_Tab);
            app.Mask2ListBox.Items = {'', '', '', ''};
            app.Mask2ListBox.ItemsData = {'1', '2', '3', '4'};
            app.Mask2ListBox.Multiselect = 'on';
            app.Mask2ListBox.ValueChangedFcn = createCallbackFcn(app, @Mask2, true);
            app.Mask2ListBox.Value = {'1'};
            app.Signal1DropDownLabel = uilabel(app.Analayze_Tab);
            app.Signal1DropDownLabel.Text = 'Signal 1';
            app.Signal1DropDown = uidropdown(app.Analayze_Tab);
            app.Signal1DropDown.Items = {'choose', '', '', '', ''};
            app.Signal1DropDown.ItemsData = {'choose', '1', '2', '3', '4'};
            app.Signal1DropDown.ValueChangedFcn = createCallbackFcn(app, @Fluo1, true);
            app.Signal1DropDown.Value = 'choose';
            app.Signal2DropDownLabel = uilabel(app.Analayze_Tab);
            app.Signal2DropDownLabel.Text = 'Signal 2';
            app.Signal2DropDown = uidropdown(app.Analayze_Tab);
            app.Signal2DropDown.Items = {'choose', '', '', '', ''};
            app.Signal2DropDown.ItemsData = {'choose', '1', '2', '3', '4'};
            app.Signal2DropDown.ValueChangedFcn = createCallbackFcn(app, @Fluo2, true);
            app.Signal2DropDown.Value = 'choose';
            app.UITable = uitable(app.Analayze_Tab);
            app.UITable.ColumnName = {'coefficients'};
            app.UITable.RowName = {'Pearson'; 'Spearman'; 'Manders M1'; 'Manders M2'};
            app.ColMap = uiaxes(app.Analayze_Tab);
            title(app.ColMap, 'Colocalization Map')
            xlabel(app.ColMap, '')
            ylabel(app.ColMap, '')
            app.ColMap.XTick = [];
            app.ColMap.YTick = [];
            app.InfoButton = uibutton(app.Analayze_Tab, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @Help_mask, true);
            app.InfoButton.Text = 'Info';
            app.InfoButton_3 = uibutton(app.Analayze_Tab, 'push');
            app.InfoButton_3.ButtonPushedFcn = createCallbackFcn(app, @Help_coefs, true);
            app.InfoButton_3.Text = 'Info';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Colocalization_analyzer_exe2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end